classdef Swabbing_test_application < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure           matlab.ui.Figure
        TakepictureButton  matlab.ui.control.Button
        ColorAX            matlab.ui.control.UIAxes
    end


    properties (Access = public)
        
     RsPipeLine realsense.pipeline

    end
    
    methods (Access = private)
        
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            RsCtx = realsense.context();
            Devs = RsCtx.query_devices();
            if ( numel(Devs) < 1 )
                error ( 'Not found RealSense device' );
            end
            RsDev = Devs{1};
            cn = RsDev.get_info(realsense.camera_info.name);
            delete(RsDev);
            delete(RsCtx);
            app.UIFigure.Name = sprintf('rs_capture_class < Camera : %s >',cn);
            app.RsPipeLine = realsense.pipeline();
            app.RsPipeLine.start();

            RsFrameSet = app.RsPipeLine.wait_for_frames();


            cf = RsFrameSet.get_color_frame();
                      

            dd = cf.get_data();
            d = permute(reshape(dd',[3,cf.get_width(),cf.get_height()]),[3 2 1]);
            imshow(d,'Parent',app.ColorAX);
          

        end

        % Button pushed function: TakepictureButton
        function TakepictureButtonPushed(app, event)
            RsFrameSet = app.RsPipeLine.wait_for_frames();


            cf = RsFrameSet.get_color_frame();
                      

            dd = cf.get_data();
            d = permute(reshape(dd',[3,cf.get_width(),cf.get_height()]),[3 2 1]);
            imshow(d,'Parent',app.ColorAX);
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.ColorAX = uiaxes(app.UIFigure);
            title(app.ColorAX, 'Title')
            xlabel(app.ColorAX, 'X')
            ylabel(app.ColorAX, 'Y')
            zlabel(app.ColorAX, 'Z')
            app.TakepictureButton = uibutton(app.UIFigure, 'push');
            app.TakepictureButton.ButtonPushedFcn = createCallbackFcn(app, @TakepictureButtonPushed, true);
            app.TakepictureButton.Text = 'Take picture';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = Swabbing_test_application

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end